classdef NetworkSolverApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        TabGroup          matlab.ui.container.TabGroup
        Tab               matlab.ui.container.Tab
        GridLayout        matlab.ui.container.GridLayout
        GridLayout4       matlab.ui.container.GridLayout
        TextArea          matlab.ui.control.TextArea
        TextAreaLabel     matlab.ui.control.Label
        GridLayout3       matlab.ui.container.GridLayout
        GridLayout8       matlab.ui.container.GridLayout
        GridLayout10      matlab.ui.container.GridLayout
        Button_7          matlab.ui.control.Button
        Button_6          matlab.ui.control.Button
        GridLayout9       matlab.ui.container.GridLayout
        Slider            matlab.ui.control.Slider
        SliderLabel       matlab.ui.control.Label
        DropDown_2        matlab.ui.control.DropDown
        DropDown_2Label   matlab.ui.control.Label
        DropDown          matlab.ui.control.DropDown
        DropDownLabel     matlab.ui.control.Label
        EditField_5       matlab.ui.control.NumericEditField
        EditField_5Label  matlab.ui.control.Label
        EditField_4       matlab.ui.control.NumericEditField
        EditField_4Label  matlab.ui.control.Label
        EditField_3       matlab.ui.control.NumericEditField
        EditField_3Label  matlab.ui.control.Label
        EditField_2       matlab.ui.control.NumericEditField
        EditField_2Label  matlab.ui.control.Label
        EditField         matlab.ui.control.NumericEditField
        EditFieldLabel    matlab.ui.control.Label
        Panel             matlab.ui.container.Panel
        GridLayout5       matlab.ui.container.GridLayout
        GridLayout7       matlab.ui.container.GridLayout
        Button_5          matlab.ui.control.Button
        Button_4          matlab.ui.control.Button
        Button_3          matlab.ui.control.Button
        Button_2          matlab.ui.control.Button
        Button            matlab.ui.control.Button
        UITable           matlab.ui.control.Table
        Tab_2             matlab.ui.container.Tab
        GridLayout11      matlab.ui.container.GridLayout
        GridLayout12      matlab.ui.container.GridLayout
        Panel_2           matlab.ui.container.Panel
        GridLayout13      matlab.ui.container.GridLayout
        UITable2          matlab.ui.control.Table
        UIAxes            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes, 'off')
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
           gps.ui.import_branches_csv_to_uitable(app.UITable);
        end

        % Button pushed function: Button_2
        function ButtonPushed2(app, event)
            gps.ui.export_uitable_to_branches_csv(app.UITable);
        end

        % Button pushed function: Button_3
        function ButtonPushed3(app, event)
            gps.ui.add_new_row_to_uitable(app.UITable);
        end

        % Button pushed function: Button_4
        function ButtonPushed4(app, event)
            % 删除后重新连续编号 ID
            gps.ui.delete_selected_rows_from_uitable(app.UITable, 'confirm', true);
        end

        % Button down function: UITable
        function UITableButtonDown(app, event)
            % 创建右键菜单
            cm = uicontextmenu(app.UIFigure);
            app.UITable.ContextMenu = cm;

            % 添加删除菜单项
            uimenu(cm, 'Text', '删除选中行', ...
                'MenuSelectedFcn', @(src, event) ...
            gps.ui.delete_selected_rows_from_uitable(app.UITable, 'confirm', true));

            % 添加不重排删除菜单项
            uimenu(cm, 'Text', '删除选中行（不重排）', ...
                'MenuSelectedFcn', @(src, event) ...
            gps.ui.delete_selected_rows_from_uitable(app.UITable, 'reindexID', false, 'confirm', true));
        end

        % Button pushed function: Button_5
        function ButtonPushed5(app, event)
            % 清空表格
            gps.ui.clear_uitable(app.UITable, 'confirm', true);
        end

        % Button pushed function: Button_6
        function ButtonPushed6(app, event)
            % 1. 禁用按钮
            app.Button_6.Enable = 'off';
            app.Button_6.Text = '求解中...';
            drawnow;

            % 2. 调用求解函数
            gps.ui.solve_network_from_ui(app);

            % 3. 恢复按钮
            app.Button_6.Enable = 'on';
            app.Button_6.Text = '开始解算';
        end

        % Button pushed function: Button_7
        function ButtonPushed7(app, event)
            % 直接读取 UITable 中的数据并绘图
            gps.ui.plot_network_graph(app.UITable, [],app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '解算';
            app.GridLayout = uigridlayout(app.Tab);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.Panel = uipanel(app.GridLayout3);
            app.Panel.Title = '分支数据';
            app.GridLayout5 = uigridlayout(app.Panel);
            app.UITable = uitable(app.GridLayout5);
            app.UITable.ColumnName = {'巷道ID'; '起点（节点ID）'; '终点（节点ID）'; '风阻'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.ColumnEditable = true;
            app.UITable.ButtonDownFcn = createCallbackFcn(app, @UITableButtonDown, true);
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.Button = uibutton(app.GridLayout7, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '导入';
            app.Button_2 = uibutton(app.GridLayout7, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed2, true);
            app.Button_2.Text = '导出';
            app.Button_3 = uibutton(app.GridLayout7, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed3, true);
            app.Button_3.Text = '新建';
            app.Button_4 = uibutton(app.GridLayout7, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed4, true);
            app.Button_4.Text = '删除';
            app.Button_5 = uibutton(app.GridLayout7, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed5, true);
            app.Button_5.Text = '清空';
            app.GridLayout8 = uigridlayout(app.GridLayout3);
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.EditFieldLabel = uilabel(app.GridLayout9);
            app.EditFieldLabel.Text = '初始风量';
            app.EditField = uieditfield(app.GridLayout9, 'numeric');
            app.EditField.Value = 100;
            app.EditField_2Label = uilabel(app.GridLayout9);
            app.EditField_2Label.Text = '入风节点';
            app.EditField_2 = uieditfield(app.GridLayout9, 'numeric');
            app.EditField_2.AllowEmpty = 'on';
            app.EditField_2.Value = [];
            app.EditField_3Label = uilabel(app.GridLayout9);
            app.EditField_3Label.Text = '回风节点';
            app.EditField_3 = uieditfield(app.GridLayout9, 'numeric');
            app.EditField_3.AllowEmpty = 'on';
            app.EditField_3.Value = [];
            app.EditField_4Label = uilabel(app.GridLayout9);
            app.EditField_4Label.Text = '最大迭代';
            app.EditField_4 = uieditfield(app.GridLayout9, 'numeric');
            app.EditField_4.Tooltip = {'最大迭代次数'};
            app.EditField_4.Value = 1000;
            app.EditField_5Label = uilabel(app.GridLayout9);
            app.EditField_5Label.Text = '收敛容差';
            app.EditField_5 = uieditfield(app.GridLayout9, 'numeric');
            app.EditField_5.Tooltip = {'判断结果收敛的条件'};
            app.EditField_5.Value = 0.001;
            app.DropDownLabel = uilabel(app.GridLayout9);
            app.DropDownLabel.Text = '求解方法';
            app.DropDown = uidropdown(app.GridLayout9);
            app.DropDown.Items = {'HardyCross', 'NewtonRaphson'};
            app.DropDown.Tooltip = {'使用的求解方法，目前只有HaedyCross迭代法可用。'; 'NewtonRaphson法作者还没看懂原理QwQ'};
            app.DropDown.Value = 'HardyCross';
            app.DropDown_2Label = uilabel(app.GridLayout9);
            app.DropDown_2Label.Text = '图表输出';
            app.DropDown_2 = uidropdown(app.GridLayout9);
            app.DropDown_2.Items = {'true', 'false'};
            app.DropDown_2.Tooltip = {'是否输出可视化图表'};
            app.DropDown_2.Value = 'true';
            app.SliderLabel = uilabel(app.GridLayout9);
            app.SliderLabel.Text = '松弛因子';
            app.Slider = uislider(app.GridLayout9);
            app.Slider.Limits = [0 2];
            app.Slider.Tooltip = {'范围(0,2]，用于改善收敛性，值越小收敛越稳定但速度越慢'};
            app.Slider.Value = 1;
            app.GridLayout10 = uigridlayout(app.GridLayout8);
            app.Button_6 = uibutton(app.GridLayout10, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed6, true);
            app.Button_6.Text = '开始解算';
            app.Button_7 = uibutton(app.GridLayout10, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed7, true);
            app.Button_7.Text = '通风网络预览';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.TextAreaLabel = uilabel(app.GridLayout4);
            app.TextAreaLabel.Text = '信息栏';
            app.TextArea = uitextarea(app.GridLayout4);
            app.TextArea.Editable = 'off';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '分析';
            app.GridLayout11 = uigridlayout(app.Tab_2);
            app.GridLayout12 = uigridlayout(app.GridLayout11);
            app.UIAxes = uiaxes(app.GridLayout12);
            title(app.UIAxes, '通风网络图')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel_2 = uipanel(app.GridLayout12);
            app.Panel_2.Title = '解算结果';
            app.GridLayout13 = uigridlayout(app.Panel_2);
            app.UITable2 = uitable(app.GridLayout13);
            app.UITable2.ColumnName = {'巷道ID'; '起点'; '终点'; '风阻'; '风量'; '风压降'};
            app.UITable2.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NetworkSolverApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end